classdef SortaSort2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        Untitled_1          matlab.ui.container.Menu
        menuOpen            matlab.ui.container.Menu
        OpenrezMenu         matlab.ui.container.Menu
        menuSettings        matlab.ui.container.Menu
        SaverezMenu         matlab.ui.container.Menu
        menuSaveNPY         matlab.ui.container.Menu
        lblUnitCounts       matlab.ui.control.Label
        uipanel4            matlab.ui.container.Panel
        cbFeatures          matlab.ui.control.CheckBox
        pbReplotGraphs      matlab.ui.control.Button
        cbAmplitudes        matlab.ui.control.CheckBox
        cbTrace             matlab.ui.control.CheckBox
        cbXCorr             matlab.ui.control.CheckBox
        cbWaveform          matlab.ui.control.CheckBox
        cbProfile           matlab.ui.control.CheckBox
        text4               matlab.ui.control.Label
        text3               matlab.ui.control.Label
        text2               matlab.ui.control.Label
        pmCol3              matlab.ui.control.DropDown
        pmCol2              matlab.ui.control.DropDown
        pmCol1              matlab.ui.control.DropDown
        tblSort             matlab.ui.control.Table
        uipanel1            matlab.ui.container.Panel
        uipanel3            matlab.ui.container.Panel
        pbSplitFet          matlab.ui.control.Button
        pbSplit             matlab.ui.control.Button
        pbSort              matlab.ui.control.Button
        pbRenumberClusters  matlab.ui.control.Button
        pbMerge             matlab.ui.control.Button
    end

    
    properties (Access = private)
        rez % where rez data is stored
        SelClusters % currently selected units
        CurrPlotClusters %
        SortedInds % indices to convert from selections in the sorted table to the data in the SortaSort field
        CurrFPath = [] % last file path accessed
        FigHandles = struct('Profile', [], ...
                            'Waveforms', [], ...
                            'Trace', [], ...
                            'XCorr', [], ...
                            'Amps', [], ...
                            'Fets', []);
        mmfFil
    end
    
    methods (Access = private)
        function metData = CalculateMetrics(app, sas)
                rez = app.rez;
                numClus = length(sas.SpkWaves);
                [numSamp, numElec, ~] = size(sas.SpkWaves{1});
                meanWaves = cell2mat(permute(sas.SpkWaves,[3 1 2]));
                metData.MeanSpkWaves = meanWaves;

                % calculate profile map for each unit
                profMap = sqrt(squeeze(sum((meanWaves-mean(meanWaves,1)).^2,1)));
                profMap(isinf(profMap)) = 0;
                profMap = bsxfun(@rdivide,profMap,max(profMap,[],1));
                metData.ProfileMap = profMap;
            
                % calculate approximation of spatial spread of waveform
                metData.Spread = sum(metData.ProfileMap,1);
            
                % calculate distances between profile maps
                profMapDists = 1-corr(profMap);
                metData.ProfileMapDist = profMapDists;
            
                % determine profile map peaks
                for j = 1:numClus
                    [~, peakInd(j)] = max(abs(profMap(:,j)));
                    peakX(j) = rez.xc(peakInd(j));
                    peakY(j) = rez.yc(peakInd(j));
                end
                metData.PeakX = peakX;
                metData.PeakY = peakY;
                metData.PeakInds = peakInd;
                metData.PeakChans = rez.ops.chanMap(peakInd);
            
                % determine centroid distances
                peakDists = sqrt((bsxfun(@minus,peakX,peakX')).^2 + (bsxfun(@minus,peakY,peakY')).^2);
                metData.PeakDists = peakDists;
            
                % get waveform similarity across all electrodes
                waveAllDists = corr(reshape(meanWaves,[numElec*numSamp numClus]));
                metData.WaveAllDists = 1-waveAllDists;
            
                % get spectral similarity across all electrodes
                waveAllSpecDists = corr(reshape(abs(fft(meanWaves,[],2)),[numElec*numSamp numClus]));
                metData.WaveAllSpecDists = 1-waveAllSpecDists;
            
                % get peak waveform similarity
                clear peakWaves;
                for j = 1:numClus
                    peakWaves(:,j) = meanWaves(:,peakInd(j),j);
                end
                metData.PeakWaves = peakWaves;
            
                wavePeakDists = corr(peakWaves);
                metData.WavePeakDists = 1-wavePeakDists;
            
                wavePeakSpecDists = corr(abs(fft(peakWaves,[],1)));
                metData.WavePeakSpecDists = 1-wavePeakSpecDists;
        end
        
        function spkWaves = ExtractSpkWaveforms(app, sas, clusterInd, numSpks)
                winInds = int64(-30:30);
                recLength = app.rez.ops.sampsToRead;
                chanMap = app.rez.ops.chanMap;
                numChan = length(chanMap);
                spkTimes = sas.GroupedSpikes{clusterInd};
                spkTimes(spkTimes<31) = [];
                spkTimes(spkTimes>(recLength-31)) = [];
                if isempty(spkTimes)
                    spkWaves = nan(61,numChan);
                else
                    subSpkTimes = spkTimes(randperm(length(spkTimes),min([length(spkTimes) numSpks])));
                    spkInds = (subSpkTimes+winInds)';
                    spkWaves = app.mmfFil.Data.file(chanMap,spkInds(:));
                    spkWaves = reshape(spkWaves,numChan,61,length(subSpkTimes));
                    spkWaves = permute(spkWaves, [2 1 3]);
                    spkWaves = double(spkWaves)-mean(spkWaves,1);
                end
        end
        
        
        function PlotAmplitudes(app)
                selClusters = app.SelClusters;
                rez = app.rez;
                figH = app.FigHandles.Amps;
                mmfFil = app.mmfFil;
                colorset = colormap('lines');
                figure(figH);
                clf(figH)
                fs = double(rez.ops.fs);
                % plot scatter of time points and amplitudes
                for j = 1:length(selClusters)
                    currClust = selClusters(j);
                    if isempty(rez.SortaSort.GroupedSpikes{currClust})
                        continue;
                    end
                    spkTimes = double(rez.SortaSort.GroupedSpikes{currClust})/fs;
                    spkAmps = double(rez.SortaSort.GroupedAmplitudes{currClust});
            
                    figure(figH);
                    set(figH,'defaultAxesColorOrder',[0 0 0; 0 0 0]);
                    yyaxis right;
                    userData = {rez selClusters mmfFil};
                    scatter(spkTimes,spkAmps,'.','MarkerEdgeColor',colorset(mod(j,64)+1,:), ...
                        'userdata',userData,'ButtonDownFcn',@Amplitudes_ButtonDownFunc);
                    ylabel('Spike Amplitudes');
            
                    hold on;
                end
                % plot firing rates across time                
                for j = 1:length(selClusters)
                    currClust = selClusters(j);
                    if isempty(rez.SortaSort.GroupedSpikes{currClust})
                        dispCluster(j) = false;
                        continue;
                    else
                        dispCluster(j) = true;
                    end
                    spkTimes = double(rez.SortaSort.GroupedSpikes{currClust})/fs;
                    % fig_lims = get(gca,'xtick');
                    if diff(get(gca,'xlim'))<1
                        [fRate, binTimes] = histcounts(spkTimes,min(get(gca,'xtick')):mode(diff(get(gca,'xtick'))):max(get(gca,'xtick')));
                    else
                        [fRate, binTimes] = histcounts(spkTimes,min(get(gca,'xtick')):max(get(gca,'xtick')));
                    end
                    
                    figure(figH);
                    set(figH,'defaultAxesColorOrder',[0 0 0; 0 0 0]);
                    yyaxis left;
                    areaH(j)= area(binTimes(2:end)-0.5,fRate, ...
                        'FaceColor',colorset(mod(j,64)+1,:), 'linestyle', 'none', ...
                        'FaceAlpha', 0.5);
                    ylabel('Firing rate (Hz)');
                    set(gca,'xticklabel', string(duration(0,0,get(gca,'xtick'), 'Format', 'hh:mm:ss')))
                    hold on;
                end
                %legend(rez.SortaSort.ClusterID(selClusters(dispCluster)));
                set(gca,'tickdir','out');
                hold off;
            
                function Amplitudes_ButtonDownFunc(source,eventdata)
                    colorset = colormap('lines');
                    rez = source.UserData{1};
                    selClusters = source.UserData{2};
                    mmfFil = source.UserData{3};
                    % timePt = datevec(eventdata.IntersectionPoint(1));
                    % timePt = (timePt(4)*3600)+(timePt(5)*60)+timePt(6);
                    timePt = int64(eventdata.IntersectionPoint(1)*double(rez.ops.fs));
                    chanMap = rez.ops.chanMap;
                	peakInds = rez.SortaSort.DistData.PeakInds;
                    uVScale = rez.SortaSort.Settings.uVScale;
                    uVSep = rez.SortaSort.Settings.uVSep;
                    tWindow = rez.SortaSort.Settings.TraceWindow;
                    filterYes = rez.SortaSort.Settings.FilterTrace;
                    sampRate = double(rez.ops.fs);
                    tWindow = int64(tWindow*rez.ops.fs);
                    peakChans = chanMap(peakInds(selClusters));
            
            
                    numTPts = size(mmfFil.Data.file,2);
            
                    filInds = timePt + [-tWindow:tWindow]';
                    filInds(filInds<1) = 1;
                    filInds(filInds>numTPts) = numTPts;
            
                    filData = double(mmfFil.data.file(peakChans,filInds))*uVScale;
                    if filterYes
                        [b, a] = butter(2, [300 14000]/(sampRate/2));
                        filData = filtfilt(b,a,filData);
                    end
                    filColors = colorset(mod(find(1:length(selClusters)),64)+1,:);
                    filTData = double(-tWindow:tWindow)/fs;
                    figure;
                    axes;
                    hold on;
                    
                    PlotStackedLines(filData, filTData, filColors, 'ABSSEP', uVSep);
                    line([0 0],ylim,'color',[0.5 0.5 0.5])
                    title('Traces around time point');
                    h = zoom;
                    h.Motion = 'horizontal';
                    h.Enable = 'on';
                    
                end
        end
        
        function PlotTraces(app, varargin)
                selClusters = app.SelClusters;
                figH = app.FigHandles.Trace;
                mmfFil = app.mmfFil;
                filterYes = app.rez.SortaSort.Settings.FilterTrace;
                colorset = colormap('lines');
                chanMap = app.rez.ops.chanMap;
                if any(strcmp(varargin, 'SELIND'))
                    selInds = varargin{find(strcmp(varargin, 'SELIND'))+1}*ones(size(selClusters));
                    plotChans = chanMap(selInds);
                else
                    peakInds = app.rez.SortaSort.DistData.PeakInds;
                    plotChans = chanMap(peakInds(selClusters));
                end
                numTPts = int64(size(mmfFil.Data.file,2));
                uVScale = app.rez.SortaSort.Settings.uVScale;
                uVSep = app.rez.SortaSort.Settings.uVSep;
                tWindow = app.rez.SortaSort.Settings.TraceWindow;
                sampRate = double(app.rez.ops.fs);
                tWindowInds = int64(round(tWindow * sampRate));
                for j = 1:length(selClusters)
                    currCluster = selClusters(j);
                    allSpikes = app.rez.SortaSort.GroupedSpikes{currCluster};
                    numSpks = length(allSpikes);
                    subInds = randperm(numSpks, min([10 numSpks]));
                    subSpks = allSpikes(subInds);
                    subSpks((subSpks<tWindowInds)|(subSpks>(numTPts-tWindowInds))) = [];
                    if isempty(subSpks)
                        figure(figH);
                        subplot(1,length(selClusters),j);
                        plot(0);
                        ylim([-uVSep uVSep*10]);
                        title(num2str(app.rez.SortaSort.ClusterID{currCluster}));
                        continue;
                    end
            
                    filInds = (subSpks + [-tWindowInds:tWindowInds])';
            
                    filData = double(mmfFil.data.file(plotChans(j),filInds(:)))*uVScale;
                    filData = reshape(filData,[(2*tWindowInds)+1 length(subSpks)]);
                    if filterYes
                        [b, a] = butter(2, [300 14000]/(sampRate/2));
                        filData = filtfilt(b,a,filData);
                    end
                    filTData = double(-tWindowInds:tWindowInds)/sampRate;
            
                    figure(figH);
                    subplot(1,length(selClusters),j);
                    PlotStackedLines(filData',filTData, colorset(mod(j,64)+1,:), ...
                        'ABSSEP', uVSep);
                    set(gca,'ytick',[]);
                    title(num2str(app.rez.SortaSort.ClusterID{currCluster}));
                    grid on;
                end
                h = zoom;
                h.Motion = 'horizontal';
                h.Enable = 'on';
        end
        
        function PlotWaveforms(app)
                selClusters = app.SelClusters;
                figH = app.FigHandles.Waveforms;
                numSamps = size(app.rez.SortaSort.DistData.MeanSpkWaves,1);
                uVScale = app.rez.SortaSort.Settings.uVScale;
                sampRate = double(app.rez.ops.fs);
                uVScaleBar = app.rez.SortaSort.Settings.uVScaleBar;
                msScaleBar = app.rez.SortaSort.Settings.msScaleBar;
                followUnit = app.rez.SortaSort.Settings.FollowUnit;

                colorset = colormap('lines');
                xCoords = app.rez.xc;
                yCoords = app.rez.yc;
                diffX = abs(bsxfun(@minus, xCoords, xCoords'));
                diffY = abs(bsxfun(@minus, yCoords, yCoords'));
                medDiffX = max([1 min(diffX(diffX~=0))]);
                medDiffY = max([1 min(diffY(diffY~=0))]);
                xScale = medDiffX/(numSamps/sampRate);
            
                traces = app.rez.SortaSort.DistData.MeanSpkWaves(:,:,selClusters)*uVScale;
                traceRanges = max(traces,[],2)-min(traces,[],2);
                traceRange = max(traceRanges(:));
                yScale = medDiffY/traceRange;
            
                xVals = cumsum(ones(size(traces)),1)/sampRate;
                % NOTE, HAD xCoords and yCoords transposed originally, should repair this
                xVals = xVals*xScale+xCoords(:)';
                yVals = traces*yScale+yCoords(:)';
                hold off;
                figure(figH);
                
                for j = 1:length(selClusters)
                    currColor = colorset(mod(j,64)+1,:);
                    
                    plot(xVals(:,:,j),yVals(:,:,j),'color',currColor);
                    hold on;
                end
            
                % plot scale bar for time
                line((max(xVals(:))+(medDiffX)/2)+[0 msScaleBar*xScale*0.001], ...
                     (min(yVals(:))-(medDiffY))*[1 1],'color','black','LineWidth',2);
                % plot scale bar for voltage
                line((max(xVals(:))+(medDiffX)/2)*[1 1], ...
                     (min(yVals(:))+(medDiffY))+[0 uVScaleBar*yScale],'color','black','LineWidth',2);
                
                hold off;
        end
        
        function PlotProfile(app)
                selClusters = app.SelClusters;
                figH = app.FigHandles.Profile;
                colorset = colormap('lines');
                xCoords = app.rez.xc;
                yCoords = app.rez.yc;
                diffX = bsxfun(@minus, xCoords, xCoords');
                diffY = bsxfun(@minus, yCoords, yCoords');
                distMat = sqrt(diffX.^2+diffY.^2);
                distMat(distMat==0) = [];
                spotDiam = min(distMat(:));
                clf(figH);
                % create data to redo plots based on where the user clicked
                % on the profile
                % data for figures
                %figData = {app.rez app.mmfFil app.FigHandles selClusters};
                figData = {app, []};
                for j = 1:length(selClusters)
                    currColor = colorset(mod(j,64)+1,:);
                    figure(figH);
                    profMap = app.rez.SortaSort.DistData.ProfileMap(:,selClusters(j));
                    for k = 1:numel(xCoords)
                        if isnan(profMap(k))
                            continue;
                        end
                        figData{2} = k;
                        rectangle('pos',[xCoords(k) yCoords(k) [1 1]+spotDiam], ...
                                  'cur',[1 1], ...
                                  'FaceColor', currColor, ...
                                  'FaceAlpha', profMap(k)/max(profMap),...
                                  'LineStyle', 'none', ...
                                  'UserData', figData, ...
                                  'ButtonDownFcn', @Profile_ButtonDownFunc);
                    end
                    hold on;
                end
                oldXLim = xlim;
                oldYLim = ylim;
                %axis equal;
                set(gca,'tickdir','out');
                xlim(oldXLim);
                ylim(oldYLim);
                grid on;
                set(gca, 'YTickLabelRotation', 90)
                hold off;

                function Profile_ButtonDownFunc(source,eventdata)
                    colorset = colormap('lines');
                    app = source.UserData{1};
                    elecInd = source.UserData{2};
                    figHandles = app.FigHandles;
                    
                    
                    % Reposition waveforms plot to be centered on selected
                    % profile plot point
                    waveH = figHandles.Waveforms;
                    if ~isempty(waveH)
                        if isvalid(waveH)
                            if isvalid(waveH.Children)
                                waveH = waveH.Children;
                                xRange = diff(xlim(waveH));
                                yRange = diff(ylim(waveH));
                                xlim(waveH, eventdata.IntersectionPoint(1)+[-xRange, xRange]/2);
                                ylim(waveH, eventdata.IntersectionPoint(2)+[-yRange, yRange]/2);
                            end
                        end
                    end
                    
                    % Replot traces using electrode selected in profile
                    % plot
                    traceH = figHandles.Trace;
                    if ~isempty(traceH)
                        if isvalid(traceH)
                            PlotTraces(app, 'SELIND', elecInd)
                        end
                    end
                end
        end
        
        function PlotXCorr(app)
                selClusters = app.SelClusters;
                figH = app.FigHandles.XCorr;
                clf(figH)
                mmfFil = app.mmfFil;
                colorset = colormap('lines');
                numClusters = length(selClusters);
                chanMap = app.rez.ops.chanMap;
                sampRate = double(app.rez.ops.fs);
                peakInds = app.rez.SortaSort.DistData.PeakInds;
                xCorrWindow = app.rez.SortaSort.Settings.XCorrWindow;
                fullCCMatrix = app.rez.SortaSort.Settings.FullCCMatrix;
                maxSampleCC = app.rez.SortaSort.Settings.MaxSampleCC;

                dt = round(sampRate*0.001);
                xCorrSamps= round((xCorrWindow*sampRate)/dt);
                binCenters = linspace(-xCorrWindow,xCorrWindow,xCorrSamps*2);
                for j = 1:numClusters
                    if length(app.rez.SortaSort.GroupedSpikes{selClusters(j)}) > 1
                        trigCluster = selClusters(j);
                        trigSpikes = app.rez.SortaSort.GroupedSpikes{trigCluster};
                        if isempty(trigSpikes)
                            continue
                        end
                        if ~isinf(maxSampleCC)
                            numSpks = length(trigSpikes);
                            numToSamp = min([numSpks, maxSampleCC]);
                            trigSpikes = sort(trigSpikes(randperm(numSpks, numToSamp)));
                            rateAdj = numSpks/numToSamp;
                        else
                            rateAdj = 1;
                        end
    
                        for k = j:numClusters
                            otherCluster = selClusters(k);
                            if k == j
                                otherSpikes = trigSpikes;
                                plotColor = colorset(mod(j,64)+1,:);
                            else
                                otherSpikes = app.rez.SortaSort.GroupedSpikes{otherCluster};
                                plotColor = [0 0 0];
                            end
                
                            if ~isfield(app.rez.SortaSort, 'CCdata')
                                app.rez.SortaSort.CCdata = cell(length(app.rez.SortaSort.GroupedSpikes));
                            end
    
                            if fullCCMatrix
                                ax = subplot(numClusters,numClusters,j+((k-1)*numClusters), ...
                                    'parent', figH);
                            else
                                if numClusters==1
                                    ax = subplot(1,1,1, 'parent', figH);
                                elseif j == 1
                                    ax = subplot(numClusters, 2, 1 + (k-1)*2, ...
                                    'parent', figH);
                                elseif k==j
                                    ax = subplot(numClusters, 2, k*2, 'parent', figH);
                                else
                                    continue;
                                end
                            end
                            
                            if isempty(app.rez.SortaSort.CCdata{trigCluster,otherCluster})
                                [spkHist, ~, relBins] = PtCrossCorr(otherSpikes,trigSpikes,...
                                    dt, xCorrSamps*int64([-1 1]));
                                app.rez.SortaSort.CCdata{trigCluster,otherCluster} = struct(...
                                    'spkHist', spkHist, 'relBins', relBins);
                            else
                                spkHist = app.rez.SortaSort.CCdata{trigCluster,otherCluster}.spkHist;
                                relBins = app.rez.SortaSort.CCdata{trigCluster,otherCluster}.relBins;
                            end
    
                            % create data to save for generting trace plots based on spike
                            % ISIs
                            isiData = {app.rez trigCluster otherCluster relBins mmfFil selClusters};
    
                            if k == j
                                spkHist(xCorrSamps) = 0;
                                fRate = sampRate/mean(diff(app.rez.SortaSort.GroupedSpikes{trigCluster}));
    
                                if isempty(fRate)
                                    fRate = 0;
                                end
                                frString = [' ' num2str(fRate,3) 'Hz'];
                            else
                                frString = '';
                            end
                            spkHistRate = spkHist/(0.001*(length(trigSpikes)/rateAdj));
                            barH = bar(ax, binCenters,spkHistRate,1, ...
                                'EdgeColor','none','FaceColor',plotColor, ...
                                'UserData', isiData, 'ButtonDownFcn',@XCorr_ButtonDownFunc);
                            if j == k
                                line(xlim, fRate*[1 1], 'color', 'black', 'LineStyle', '--');
                            end
                            if j~=k
                                ylim([0 max([spkHistRate*1.1; 2*median(spkHistRate); 0.0001])]) % added magic number to prevent [0 0] condition
                                title([num2str(app.rez.SortaSort.ClusterID{trigCluster}) '<-' ...
                                    num2str(app.rez.SortaSort.ClusterID{otherCluster}) '  |  ' ...
                                    num2str(app.rez.SortaSort.ClusterID{trigCluster}) '->' ...
                                    num2str(app.rez.SortaSort.ClusterID{otherCluster})]);
                            else
                                ylim([0 max([spkHistRate*1.1; 2*median(spkHistRate); fRate*1.1])])
                                title([num2str(app.rez.SortaSort.ClusterID{trigCluster}) '<->' ...
                                num2str(app.rez.SortaSort.ClusterID{otherCluster}) frString]);
                            end
                            set(gca,'xtick',0);
                            set(gca,'ytick',[]);
                            set(gca,'tickdir','out');
                            xlim([min(binCenters) max(binCenters)]);
    
                            %drawnow;
                        end
                    end                    
                end
            
                function XCorr_ButtonDownFunc(source,eventdata)
                    colorset = colormap('lines');
                    numBins = size(source.UserData{4},2);
                    rez = source.UserData{1};
                    trigCluster = source.UserData{2};
                    otherCluster = source.UserData{3};
                    mmfFil = source.UserData{5};
                    selClusters = source.UserData{6};
                    uVScale = rez.SortaSort.Settings.uVScale;
                    uVSep = rez.SortaSort.Settings.uVSep;
                    sampleRate = double(rez.ops.fs);
                    filterYes = rez.SortaSort.Settings.FilterTrace;
                    
                    % FIX XCORR WINDOWS TO WORK WITH SAMPLES
                    xCorrSamps = int64(round(rez.SortaSort.Settings.XCorrWindow*rez.ops.fs));
                    chanMap = rez.ops.chanMap;
                	peakInds = rez.SortaSort.DistData.PeakInds;
                    trigChan = chanMap(peakInds(trigCluster));
                    otherChan = chanMap(peakInds(otherCluster));
            
                    if trigCluster == otherCluster
                        arrowStr = '<->';
                    else
                        arrowStr = '->';
                    end
            
                    centerBin = ceil(numBins/2);
                    selBin = round(eventdata.IntersectionPoint(1)/0.001)+centerBin;
            
                    if selBin > numBins
                        selBin = numBins;
                    end
                    
                    subSpks = rez.SortaSort.GroupedSpikes{trigCluster}(source.UserData{4}(:,selBin)>0); 
                    if length(subSpks)>10
                        subSpks = subSpks(randperm(length(subSpks),10));
                    end
                    numTPts = size(mmfFil.Data.file,2);
                    if isempty(subSpks)
                        return;
                    end
                    filInds = (subSpks + [-xCorrSamps:xCorrSamps])';
                    filInds(filInds<1) = 1;
                    filInds(filInds>numTPts) = numTPts;
                    trigData = double(mmfFil.data.file(trigChan,filInds(:)))*uVScale;
                    otherData = double(mmfFil.data.file(otherChan,filInds(:)))*uVScale;
                    trigData = reshape(trigData,[(2*xCorrSamps)+1 length(subSpks)]);
                    otherData = reshape(otherData,[(2*xCorrSamps)+1 length(subSpks)]);

                    if filterYes
                        [b,a] = butter(2, [300 14000]/(sampleRate/2));
                        trigData = filtfilt(b,a,trigData);
                        otherData = filtfilt(b,a,otherData);
                    end
                    trigColor = colorset(mod(find(trigCluster==selClusters),64)+1,:);
                    otherColor = colorset(mod(find(otherCluster==selClusters),64)+1,:);
                    filTData = double(-xCorrSamps:xCorrSamps)/double(rez.ops.fs);
                    figure;
                    axes;
                    ylim([-uVSep ((length(subSpks)-1)*uVSep)+uVSep]);
                    hold on;
                    line([0 0],ylim,'color',[0.5 0.5 0.5])
                    line([1 1]*((selBin-centerBin)/1000),ylim,'color',[0.5 0.5 0.5])
                    PlotStackedLines(trigData', filTData, trigColor, 'ABSSEP', uVSep);
            
                    if trigChan ~= otherChan
                        hold on;
                        PlotStackedLines(otherData', filTData, otherColor, 'ABSSEP', uVSep);
                    end
            
                    title([num2str(rez.SortaSort.ClusterID{trigCluster}) arrowStr ...
                            num2str(rez.SortaSort.ClusterID{otherCluster}) ...
                            ' lagged by ' num2str(selBin-centerBin) ' ms']);
                    h = zoom;
                    h.Motion = 'horizontal';
                    h.Enable = 'on';
                end
        end
        
        function UpdatePlots(app)
                numPlots = 6;
                
                waitH = waitbar(0,'Starting plotting');
                followUnit = app.rez.SortaSort.Settings.FollowUnit;
                selClusters = app.SelClusters;

                figure(waitH)
                if app.cbWaveform.Value
                    if isempty(app.FigHandles.Waveforms) || ~ishghandle(app.FigHandles.Waveforms)
                        app.FigHandles.Waveforms = figure('Name', 'Waveforms', 'UserData',app);
                        firstTime = true;
                    else
                        firstTime = false;
                    end
                    figure(app.FigHandles.Waveforms);
                    origXLim = xlim();
                    origYLim = ylim();

                    
                    waitbar(1/numPlots,waitH,'Plotting waveforms');
                    
                    PlotWaveforms(app)
                    if ~firstTime && ~followUnit
                        xlim(origXLim);
                        ylim(origYLim);
                    elseif ~firstTime && followUnit
                        xRange = diff(origXLim);
                        yRange = diff(origYLim);
                        xPeak = app.rez.SortaSort.DistData.PeakX(selClusters(1));
                        yPeak = app.rez.SortaSort.DistData.PeakY(selClusters(1));
                        xlim(xPeak + [-0.5 0.5] * xRange);
                        ylim(yPeak + [-0.3 0.7] * yRange);
                    end
                end

                figure(waitH)
                if app.cbTrace.Value
                    if isempty(app.FigHandles.Trace) || ~ishghandle(app.FigHandles.Trace)
                        app.FigHandles.Trace = figure('name','Traces','UserData',app);
                    end
                    figure(app.FigHandles.Trace);
                    waitbar(2/numPlots,waitH,'Plotting traces');
                    PlotTraces(app)
                end

                figure(waitH)
                if app.cbXCorr.Value
                    if isempty(app.FigHandles.XCorr) || ~ishghandle(app.FigHandles.XCorr)
                        app.FigHandles.XCorr = figure('name','Cross-correlation', ...
                                                      'UserData',app);
                    end
                    figure(app.FigHandles.XCorr);
                    waitbar(3/numPlots,waitH,'Plotting cross-correlations');
                    PlotXCorr(app)
                end

                figure(waitH)
                if app.cbAmplitudes.Value
                    if isempty(app.FigHandles.Amps) || ~ishghandle(app.FigHandles.Amps)
                        app.FigHandles.Amps = figure('name', 'Amplitudes', 'UserData',app);
                    end
                    figure(app.FigHandles.Amps);
                    waitbar(4/numPlots,waitH,'Plotting amplitudes');
                    PlotAmplitudes(app)
                end

                figure(waitH)
                if app.cbProfile.Value
                    if isempty(app.FigHandles.Profile) || ~ishghandle(app.FigHandles.Profile)
                        app.FigHandles.Profile = figure('name','Profile', 'UserData',app);
                    end
                    figure(app.FigHandles.Profile);
            
                    %clf('reset'); % FIX
                    set(gcf,'name','Spatial Profile');
                    waitbar(5/numPlots,waitH,'Plotting profile');
                    PlotProfile(app)
                end
                figure(app.figure1);
                close(waitH);
                app.CurrPlotClusters = app.SelClusters;
                
            
        end
        
        function UpdateTable(app)
                sas = app.rez.SortaSort;
                selCols = {app.pmCol1.Value; ...
                           app.pmCol2.Value; ...
                           app.pmCol3.Value};
            
                % determine if a particular cluster is selected, otherwise orient to
                % first cluster
                if isempty(app.SelClusters)
                    selClusters = 1;
                else
                    selClusters = app.SelClusters;
                end
            
                sortCols = cell(4,1);
                sortCols{1} = arrayfun(@(x)char(x),sas.ClusterType,'uniformoutput',false);
                uCount = sum(strcmp(string(sas.ClusterType), 'Unknown'));
                nCount = sum(strcmp(string(sas.ClusterType), 'Noise'));
                mCount = sum(strcmp(string(sas.ClusterType), 'MU'));
                sCount = sum(strcmp(string(sas.ClusterType), 'SU'));
                app.lblUnitCounts.Text = ['Unknown: ' num2str(uCount) '  ' ...
                                          'Noise: ' num2str(nCount) '  ' ...
                                          'MU: ' num2str(mCount) '  ' ...
                                          'SU: ' num2str(sCount)];
                numRows = length(sas.ClusterType);
                colNames = {'AssignClusterType'};
                rowNames = sas.ClusterID;
                colTypes{1} = {'Unknown' 'Noise' 'MU' 'SU'};
                removeStyle(app.tblSort);
                for j = 1:length(selCols)
                    currCol = selCols{j};
                    switch currCol
                        case 'Cluster ID'
                            sortCols{j+1} = str2num(strjoin(sas.ClusterID,' '))';
                            dispCols{j+1} = num2cell(sortCols{j+1});
                            rowInds{j+1} = false(numRows,1);
                            colColor{j+1} = [0 0 0];
                            colNames{j+1} = 'ClusterID';
                            colTypes{j+1} = 'char';
                            colSortDir{j} = 'ascend';
                        case 'Cluster Type'
                            sortCols{j+1} = arrayfun(@(x)char(x),sas.ClusterType,'uniformoutput',false);
                            dispCols{j+1} = sortCols{j+1};
                            rowInds{j+1} = false(numRows,1);
                            colColor{j+1} = [0 0 0];
                            colNames{j+1} = 'ClusterType';
                            colTypes{j+1} = 'char';
                            colSortDir{j} = 'descend';
                        case 'Spatial Profile'
                            sortCols{j+1} = mean(sas.DistData.ProfileMapDist(selClusters,:),1)';
                            dispCols{j+1} = num2cell(sortCols{j+1}); %arrayfun(@(x)colorGen(255*(sqrt(2)-sqrt(x))/sqrt(2),x), sortCols{j+1},'uniformoutput',false);
                            rowInds{j+1} = sortCols{j+1}(:)<0.02;
                            colColor{j+1} = [0.6 0.6 1];
                            colNames{j+1} = 'SpatialProfile';
                            colTypes{j+1} = 'numeric';
                            colSortDir{j} = 'ascend';
                        case 'Peak Location'
                            sortCols{j+1} = mean(sas.DistData.PeakDists(selClusters,:),1)';
                            dispCols{j+1} = num2cell(sortCols{j+1}); %arrayfun(@(x)colorGen(255*(sqrt(max(x))-sqrt(x))/sqrt(max(x)),x), ...
                            rowInds{j+1} = sortCols{j+1}(:)<60;
                            colColor{j+1} = [0 0.6 1];
                            colNames{j+1} = 'PeakLocation';
                            colTypes{j+1} = 'numeric';
                            colSortDir{j} = 'ascend';
                        case 'Complete Waveform'
                            sortCols{j+1} = mean(sas.DistData.WaveAllDists(selClusters,:),1)';
                            dispCols{j+1} = num2cell(sortCols{j+1}); %arrayfun(@(x)colorGen(255*(sqrt(2)-sqrt(x))/sqrt(2),x), ...
                            rowInds{j+1} = sortCols{j+1}(:)<0.02;
                            colColor{j+1} = [0.6 0.6 1];
                            colNames{j+1} = 'CompleteWaveform';
                            colTypes{j+1} = 'numeric';
                            colSortDir{j} = 'ascend';
                        case 'Complete Spectrum'
                            sortCols{j+1} = mean(sas.DistData.WaveAllSpecDists(selClusters,:),1)';
                            dispCols{j+1} = num2cell(sortCols{j+1}); %arrayfun(@(x)colorGen(255*(sqrt(2)-sqrt(x))/sqrt(2),x), ...
                            rowInds{j+1} = sortCols{j+1}(:)<0.02;
                            colColor{j+1} = [0.6 0.6 1];
                            colNames{j+1} = 'CompleteSpectrum';
                            colTypes{j+1} = 'numeric';
                            colSortDir{j} = 'ascend';
                        case 'Peak Waveform'
                            sortCols{j+1} = mean(sas.DistData.WavePeakDists(selClusters,:),1)';
                            dispCols{j+1} = num2cell(sortCols{j+1}); %arrayfunfun(@(x)colorGen(255*(sqrt(2)-sqrt(x))/sqrt(2),x), ...
                            rowInds{j+1} = sortCols{j+1}(:)<0.02;
                            colColor{j+1} = [0.6 0.6 1];
                            colNames{j+1} = 'PeakWaveform';
                            colTypes{j+1} = 'numeric';
                            colSortDir{j} = 'ascend';
                        case 'Peak Spectrum'
                            sortCols{j+1} = mean(sas.DistData.WavePeakSpecDists(selClusters,:),1)';
                            dispCols{j+1} = num2cell(sortCols{j+1}); %arrayfun(@(x)colorGen(255*(sqrt(2)-sqrt(x))/sqrt(2),x), ...
                            rowInds{j+1} = sortCols{j+1}(:)<0.02;
                            colColor{j+1} = [0.6 0.6 1];
                            colNames{j+1} = 'PeakSpectrum';
                            colTypes{j+1} = 'numeric';
                            colSortDir{j} = 'ascend';
                        case 'Peak Channel'
                            sortCols{j+1} = sas.DistData.PeakChans;
                            dispCols{j+1} = num2cell(sortCols{j+1});
                            rowInds{j+1} = false(numRows,1);
                            colColor{j+1} = [0 0 0];
                            colNames{j+1} = 'PeakChannel';
                            colTypes{j+1} = 'numeric';
                            colSortDir{j} = 'ascend';
                        case 'Spread'
                            sortCols{j+1} = sas.DistData.Spread';
                            dispCols{j+1} = num2cell(sortCols{j+1}); %cellfun(@(x)colorGen(255*(sqrt(max(x))-sqrt(x))/sqrt(max(x)),x), ...
                            rowInds{j+1} = sortCols{j+1}(:)<50;
                            colColor{j+1} = [0.4 0.6 1];
                            colNames{j+1} = 'Spread';
                            colTypes{j+1} = 'numeric';
                            colSortDir{j} = 'ascend';
                        case 'Peak X'
                            sortCols{j+1} = sas.DistData.PeakX';
                            dispCols{j+1} = num2cell(sortCols{j+1});
                            rowInds{j+1} = abs(sortCols{j+1}(:)-mean(sas.DistData.PeakX(selClusters)))<60;
                            colColor{j+1} = [0 0.6 1];                            
                            colNames{j+1} = 'PeakX';
                            colTypes{j+1} = 'numeric';
                            colSortDir{j} = 'ascend';
                         case 'Peak Y'
                            sortCols{j+1} = sas.DistData.PeakY';
                            dispCols{j+1} = num2cell(sortCols{j+1});
                            rowInds{j+1} = abs(sortCols{j+1}(:)-mean(sas.DistData.PeakY(selClusters)))<60;
                            colColor{j+1} =  [0 0.6 1];
                            colNames{j+1} = 'PeakY';
                            colTypes{j+1} = 'numeric';
                            colSortDir{j} = 'ascend';
                    end
                end
            
                distTable = table(sortCols{1},sortCols{2},sortCols{3},sortCols{4});
                [distTable, sortInds] = sortrows(distTable,[2 3 4],colSortDir);
                app.tblSort.ColumnFormat = colTypes;
                app.tblSort.Data = [sortCols{1}(sortInds), dispCols{2}(sortInds), ...
                                        dispCols{3}(sortInds), dispCols{4}(sortInds)];
                app.tblSort.ColumnEditable = [true false false false];
                app.tblSort.ColumnName = colNames;
                app.tblSort.RowName = rowNames(sortInds);
                ColorizeCol(app, 2, colColor{2}, find(rowInds{2}(sortInds)))   
                ColorizeCol(app, 3, colColor{3}, find(rowInds{3}(sortInds)))   
                ColorizeCol(app, 4, colColor{4}, find(rowInds{4}(sortInds)))   
                app.SortedInds = sortInds;
                
                
                for j = 1:length(selClusters)
                    tblSelectInds(j,1) = find(strcmp(app.rez.SortaSort.ClusterID(selClusters(j)), ...
                                                   app.tblSort.RowName));
                end
                
                % .Selection is undocumented matlab
                app.tblSort.Selection = [tblSelectInds ones(size(tblSelectInds))*2];
%               
%                 
%                 if ~isempty(app.tblSort.Selection)
%                     if ~any(app.tblSort.Selection(:,2)==1) 
%                         
%                         tblSortInds = unique(app.tblSort.Selection(:,1));
%                         
%                         selClusters = app.tblSort.RowName(tblSortInds);
%                         for j = 1:length(selClusters)
%                             clusterInds(j) = find(strcmp(selClusters(j),app.rez.SortaSort.ClusterID));
%                         end
%                         app.SelClusters = clusterInds;
%                     end
%                 end
        end
        
        
        function ColorizeCol(app, selCol, hsvColor, inds)
            if ~isempty(inds)
                addStyle(app.tblSort,uistyle("BackgroundColor",...
                    hsv2rgb(hsvColor)),'cell',[inds selCol*ones(size(inds))]);
        
            end
        end

    end    
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SortaSort_OpeningFcn(app, varargin)
            
            movegui(app.figure1, 'center');
            % Have to add callback for keypress in uitable here because app
            % designer will not let me add it using the GUI or in the
            % ui object declarations sections
            app.tblSort.KeyPressFcn = @KeyPressCallback;
            app.figure1.KeyPressFcn = @KeyPressCallback;

            function KeyPressCallback(source, eventdata)
                key = eventdata.Key;
                switch key
                    case 'n'
                        selCat = {'Noise'};
                    case 'm'
                        selCat = {'MU'};
                    case 's'
                        selCat = {'SU'};
                    case 'u'
                        selCat = {'Unknown'};
                    otherwise
                        return;
                end
                app.rez.SortaSort.ClusterType(app.SelClusters) = categorical(selCat, ...
                    {'Unknown' 'Noise' 'MU' 'SU'});

                UpdateTable(app);
                app.tblSort.Selection = [1 2];
                app.SelClusters = find(strcmp(app.tblSort.RowName{1}, ...
                                              app.rez.SortaSort.ClusterID));
                UpdatePlots(app);
                UpdateTable(app);
            end


        end

        % Value changed function: cbAmplitudes
        function cbAmplitudes_Callback(app, event)

            if event.Value
                UpdatePlots(app)
            end
        end

        % Value changed function: cbFeatures
        function cbFeatures_Callback(app, event)
           
        end

        % Value changed function: cbProfile
        function cbProfile_Callback(app, event)
            
            if event.Value
                UpdatePlots(app)
            end
        end

        % Value changed function: cbTrace
        function cbTrace_Callback(app, event)
            if event.Value
                UpdatePlots(app)
            end
        end

        % Value changed function: cbWaveform
        function cbWaveform_Callback(app, event)
            if event.Value
                UpdatePlots(app)
            end
        end

        % Value changed function: cbXCorr
        function cbXCorr_Callback(app, event)
            
            if event.Value
                UpdatePlots(app)
            end
        end

        % Menu selected function: menuOpen
        function menuOpen_Callback(app, event)
                if ~isempty(app.CurrFPath)
                    if ischar(app.CurrFPath)
                        if isfolder(app.CurrFPath)
                            cd(app.CurrFPath);
                        end
                        fPath = uigetdir(app.CurrFPath, 'Select Kilosort output directory');
                    else
                        fPath = uigetdir([], 'Select Kilosort output directory');
                    end
                else
                    fPath = uigetdir([], 'Select Kilosort output directory');
                end
                app.CurrFPath = fPath;
                
                % load output from Kilosort4
                parPath = fullfile(fPath, 'params.py');
                ampsPath = fullfile(fPath, 'amplitudes.npy');
                cluPath = fullfile(fPath, 'spike_clusters.npy');
                timesPath = fullfile(fPath, 'spike_times.npy');
                chmapPath = fullfile(fPath, 'channel_map.npy');
                chposPath = fullfile(fPath, 'channel_positions.npy');
                
                params = fileread(parPath);

                % get binary file path
                binPath = regexp(params, "dat_path = \'(.*\.(bin|dat|bat|raw))\'", 'TOKENS');
                if isempty(binPath)
                    rez.ops.fbinary = [];
                else
                    rez.ops.fbinary = binPath{1}{1};
                end
                % create index into binary data
                filProp = dir(rez.ops.fbinary);
                if isempty(filProp)
                    [binFName, binFPath] = uigetfile({'*.bin', '*.dat', '*.raw', '*.bat'}, ...
                                            'Binary file not found, please locate it:');
                    rez.ops.fbinary = fullfile(binFPath, binFName);
                    filProp = dir(rez.ops.fbinary);
                end
                
                % fill in ops
                dType = regexp(params, "dtype = \'([a-z0-9]*)\'", 'TOKENS');
                nChanTot = regexp(params, "n_channels_dat = (\d*)", 'TOKENS');
                fs = regexp(params, "sample_rate = (\d*)", 'TOKENS');
                rez.ops.NchanTOT = str2num(nChanTot{1}{1});
                rez.ops.sampsToRead = filProp.bytes/(rez.ops.NchanTOT*2);
                rez.ops.fs = int64(str2num(fs{1}{1}));
                chPos = readNPY(chposPath);
                rez.ops.chanMap = readNPY(chmapPath)+1;
                rez.ops.dType = dType{1}{1};
                rez.xc = chPos(:,1);
                rez.yc = chPos(:,2);


                app.mmfFil = memmapfile(rez.ops.fbinary,'format',{rez.ops.dType ...
                [rez.ops.NchanTOT rez.ops.sampsToRead] 'file'});


                rez.SortaSort.Settings = struct('uVScale', 2.343, ... % convert binary values to microvolts
                                                'uVSep', 100, ... % separation between voltage traces for trace plots
                                                'XCorrWindow', 0.02, ... % duration of the XCorr window in seconds
                                                'TraceWindow', 0.004, ... % separation between voltage traces for trace plots
                                                'uVScaleBar', 25, ... % for scaling the waveforms plot
                                                'msScaleBar', 1, ... % for scaling the waveforms plot
                                                'MaxSelected', 10, ... % maximum number simultaneously selected clusters
                                                'MaxSampleCC', inf, ... % maximum number of spikes to use when calculating the CC
                                                'FullCCMatrix', false, ... % plot cc between each pair of units
                                                'FollowUnit', false, ... % update waveform plot to center on selected unit
                                                'FilterTrace', true, ... % high-pass filter traces for unit activity (300-5000Hz)
                                                'AutoReplot', true); % automatically replot data with each selection
                                                
                clus = readNPY(cluPath);
                clusterIDs = unique(clus);
                times = readNPY(timesPath);
                amps = readNPY(ampsPath);

                numClusters = length(clusterIDs);
                rez.st3 = [times, clus, amps];
                
                rez.SortaSort.ClusterID = arrayfun(@num2str,clusterIDs,'uniformoutput',false);
                rez.SortaSort.ClusterType = categorical(repmat({'Unknown'},numClusters,1),...
                    {'Unknown' 'Noise' 'MU' 'SU'});
                rez.SortaSort.GroupedSpikes = arrayfun(@(x)rez.st3(rez.st3(:,2)==x,1),...
                    clusterIDs,'uniformoutput',false);
                rez.SortaSort.GroupedAmplitudes = arrayfun(@(x)rez.st3(rez.st3(:,2)==x,3),...
                    clusterIDs,'uniformoutput',false);

                app.rez = rez;
                for j = 1:numClusters
                    meanWave = median(ExtractSpkWaveforms(app, rez.SortaSort, j, 100),3);
                    app.rez.SortaSort.SpkWaves{j} = meanWave;
                    
                    [~, maxChan] = max(sum(meanWave.^2,1));
                    [minVal, minInd] = min(meanWave(:,maxChan));
                    [maxVal, maxInd] = max(meanWave(:,maxChan));

                    if maxVal > (-2*minVal)
                        peakInd = maxInd;
                    else
                        peakInd = minInd;
                    end
                    
                    if peakInd ~= 31
                        shiftInd = peakInd-31;
                        app.rez.SortaSort.GroupedSpikes{j} = app.rez.SortaSort.GroupedSpikes{j}+shiftInd;
                        meanWave = median(ExtractSpkWaveforms(app, app.rez.SortaSort, j, 100),3);
                        app.rez.SortaSort.SpkWaves{j} = meanWave;
                    end
                end
    
                app.rez.SortaSort.DistData = CalculateMetrics(app, app.rez.SortaSort);

                app.SelClusters = 1;
                app.CurrPlotClusters = nan;
        
                app.figure1.Name = ['SortaSort' fPath];
                UpdateTable(app)
                disp('Loaded npy')

        end

        % Menu selected function: SaverezMenu
        function menuSave_Callback(app, event)
           
            try
                currDir = cd;
                if ischar(app.CurrFPath)
                    if isfolder(app.CurrFPath)
                        cd(app.CurrFPath);
                    end
                end
                rez = app.rez;
                [file, path] = uiputfile('*.mat', 'Save rez file:');
                app.CurrFPath = path;
                [~,~,ext] = fileparts(file);
                if isempty(ext)
                    file = [file '.mat'];
                end
                save(fullfile(path,file), 'rez', '-v7.3')
                disp('Saved rez')
                cd(currDir);
            catch
                disp('Nothing to save')
            end
        end

        % Menu selected function: menuSettings
        function menuSettings_Callback(app, event)

                settingsOld = app.rez.SortaSort.Settings;
                fNames = fieldnames(settingsOld);
                oldSetVals = struct2cell(settingsOld);
                settingsNew = struct();

                for j = 1:length(fNames)
                    if any(strcmp({'FullCCMatrix', 'FollowUnit', ...
                            'FilterTrace', 'AutoReplot'}, fNames{j}))
                        format(j,1).type = 'check';
                        format(j,1).format = 'logical';
                        format(j,1).size = 60;
                    else
                        format(j,1).type = 'edit';
                        format(j,1).format = 'float';
                        format(j,1).size = 60;
                    end
                end
                
                setOut = inputsdlg(fNames,'Settings',format, oldSetVals);

                for j = 1:length(setOut)
                    settingsNew.(fNames{j}) = setOut{j};
                end
                
                % if CC parameters were changed, erase temporary CC data
                if (settingsNew.XCorrWindow ~= settingsOld.XCorrWindow) || ...
                        (settingsNew.MaxSampleCC ~= settingsOld.MaxSampleCC)
                    app.rez.SortaSort.CCdata = cell(length(app.rez.SortaSort.GroupedSpikes));
                end

                app.rez.SortaSort.Settings = settingsNew;


        end

        % Button pushed function: pbMerge
        function pbMerge_Callback(app, event)
                app.tblSort.Enable = "off";
                sas = app.rez.SortaSort;
                selClusters = app.SelClusters;
                if length(selClusters)<2
                    return;
                end
                [allSpikes, sInds] = sort(cell2mat(sas.GroupedSpikes(selClusters)));
                uniqSpks = [true; diff(allSpikes)>(app.rez.ops.fs*0.001)]; % remove spikes that are adjacent by 1 ms
                allSpikes = allSpikes(uniqSpks);
          
                sas.ClusterID(selClusters) = [];
                sas.ClusterID{end+1} = num2str(max(cellfun(@str2num,sas.ClusterID))+1);
                sas.ClusterType(selClusters) = [];
                sas.ClusterType(end+1) = categorical({'Unknown'},...
                    {'Unknown' 'Noise' 'MU' 'SU'});
            
                sas.GroupedSpikes(selClusters) = [];
                sas.GroupedSpikes{end+1} = allSpikes;
            
                % not optimal, should find a good way to rescale amplitudes given the
                % new average spike waveforms.
                allAmps = cell2mat(cellfun(@int64, sas.GroupedAmplitudes(selClusters),'uniformoutput',false));
                allAmps = allAmps(sInds);
                allAmps = allAmps(uniqSpks);
                sas.GroupedAmplitudes(selClusters) = [];
                sas.GroupedAmplitudes{end+1} = allAmps;
            
                sas.SpkWaves(selClusters) = [];
               
                sas.SpkWaves{end+1} = median(ExtractSpkWaveforms(app, sas, ...
                    length(sas.GroupedSpikes), 100),3);

                % remove CCs of merged units and add space for CC for new
                % unit
                if isfield(sas, 'CCdata')
                    ccData = sas.CCdata;
                    ccData(:, selClusters) = [];
                    ccData(selClusters, :) = [];
                    ccData(:, end+1) = cell(size(ccData,1), 1);
                    ccData(end+1, :) = cell(1, size(ccData,2));
                    sas.CCdata = ccData;
                end

                sas.DistData = CalculateMetrics(app, sas);
                app.rez.SortaSort = sas;
                app.SelClusters = numel(sas.ClusterID);
                UpdatePlots(app);
                UpdateTable(app);
                app.tblSort.Enable = "on";
        end

        % Button pushed function: pbRenumberClusters
        function pbRenumberClusters_Callback(app, event)
           
                newClusterIDs = arrayfun(@num2str, 1:numel(app.rez.SortaSort.ClusterID), ...
                    'uniformoutput',false);
                app.rez.SortaSort.ClusterID(app.SortedInds) = newClusterIDs;
                UpdatePlots(app);
                UpdateTable(app);
        end

        % Button pushed function: pbReplotGraphs
        function pbReplotGraphs_Callback(app, event)
          
                UpdatePlots(app);
        end

        % Button pushed function: pbSort
        function pbSort_Callback(app, event)
             UpdateTable(app)
        end

        % Button pushed function: pbSplit
        function pbSplit_Callback(app, event)
                app.tblSort.Enable = "off";
                sas = app.rez.SortaSort;
                selClusters = app.SelClusters;
                
                if length(selClusters)~=1
                    errordlg('Only one cluster can be split at a time');
                    return;
                end
            
                if isempty(app.FigHandles.Amps) || ~ishghandle(app.FigHandles.Amps)
                    app.FigHandles.Amps = figure('name','Amplitudes','UserData',app);
                else
                    figure(app.FigHandles.Amps);
                end
                
                PlotAmplitudes(app);
                yyaxis right;
                polyH = drawpolygon();
                if isempty(polyH.Position)
                    app.tblSort.Enable = "on";
                    return;
                end
                polyPos = polyH.Position;
                polyH.delete;
                if ~isempty(polyPos)
                    origSpks = sas.GroupedSpikes{selClusters};
                    spkTimes = double(origSpks)/double(app.rez.ops.fs);
                    spkAmps = double(sas.GroupedAmplitudes{selClusters});
                    inSpks = inpolygon(spkTimes,spkAmps,polyPos(:,1),polyPos(:,2));
                
                
                    sas.ClusterID{end+1} = num2str(max(cellfun(@str2num,sas.ClusterID))+1);
                    sas.ClusterType([selClusters end+1]) = categorical({'Unknown' 'Unknown'},...
                    {'Unknown' 'Noise' 'MU' 'SU'});
                
                    sas.GroupedSpikes{selClusters} = origSpks(inSpks);
                    sas.GroupedSpikes{end+1} = origSpks(~inSpks);
                
                    sas.GroupedAmplitudes{selClusters} = int64(spkAmps(inSpks));
                    sas.GroupedAmplitudes{end+1} = int64(spkAmps(~inSpks));
                    
                    % retrieve a new set of spike waveforms using the new clusters
                    for j = [selClusters length(sas.GroupedSpikes)]
                        sas.SpkWaves{j} = median(ExtractSpkWaveforms(app, sas, j, 100),3);
                    end
                    
                    
                    % erase CCs of selected unit and add space for CC for new
                    % split unit
                    if isfield(sas, 'CCdata')
                        ccData = sas.CCdata;
                        ccData(:, selClusters) = cell(size(ccData,1), 1);
                        ccData(selClusters, :) = cell(1, size(ccData,2));
                        ccData(:, end+1) = cell(size(ccData,1), 1);
                        ccData(end+1, :) = cell(1, size(ccData,2));
                        sas.CCdata = ccData;
                    end
                    
                    app.SelClusters = [app.SelClusters numel(sas.ClusterID)];
                    sas.DistData = CalculateMetrics(app, sas);
                    app.rez.SortaSort = sas;
                    UpdatePlots(app);
                    UpdateTable(app);
                end
                app.tblSort.Enable = "on";
        end

        % Value changed function: pmCol1
        function pmCol1_Callback(app, event)
            UpdateTable(app)
        end

        % Value changed function: pmCol2
        function pmCol2_Callback(app, event)
            UpdateTable(app)
        end

        % Value changed function: pmCol3
        function pmCol3_Callback(app, event)
            UpdateTable(app)
        end

        % Cell edit callback: tblSort
        function tblSort_CellEditCallback(app, event)
            replotYes = app.rez.SortaSort.Settings.AutoReplot;
            editedInd = event.Indices(1);
            editedCluster = app.tblSort.RowName(editedInd);
            clusterInd = find(strcmp(editedCluster,app.rez.SortaSort.ClusterID));
            app.rez.SortaSort.ClusterType(clusterInd) = categorical(cellstr(event.NewData), ...
                {'Unknown' 'Noise' 'MU' 'SU'});

            if replotYes
                UpdatePlots(app);
            end
            UpdateTable(app);
            app.SelClusters = find(strcmp(app.tblSort.RowName{1}, ...
                                              app.rez.SortaSort.ClusterID));
        end

        % Cell selection callback: tblSort
        function tblSort_CellSelectionCallback(app, event)
            replotYes = app.rez.SortaSort.Settings.AutoReplot;
            if isempty(event.Indices) || any(event.Indices(:,2)==1)
                return;
            end
            
            tblSortInds = unique(event.Indices(:,1), 'stable');
            selClusters = app.tblSort.RowName(tblSortInds);
            
            if length(selClusters) > app.rez.SortaSort.Settings.MaxSelected
                selClusters((app.rez.SortaSort.Settings.MaxSelected+1):end) = [];
            end

            for j = 1:length(selClusters)
                clusterInds(j) = find(strcmp(selClusters(j),app.rez.SortaSort.ClusterID));
            end
            
            if ~isequal(app.SelClusters, clusterInds)
                app.SelClusters = clusterInds;
                if replotYes
                    UpdatePlots(app);
                end
            end
            
        end

        % Menu selected function: menuSaveNPY
        function menuSaveNPYMenuSelected(app, event)
            try
                currDir = cd;
                rez = app.rez;
                if ischar(app.CurrFPath)
                    if isfolder(app.CurrFPath)
                        cd(app.CurrFPath);
                    end
                end
                cd(app.CurrFPath)
                [suffix, fPath] = uiputfile('.npy', 'Save as npy, enter suffix', '_sas');
                app.CurrFPath = fPath;
                cluPath = fullfile(fPath, ['spike_clusters' suffix]);
                timesPath = fullfile(fPath, ['spike_times' suffix]);
                groupPath = fullfile(fPath, ['cluster_group' suffix(1:(end-3)) 'tsv']);
                
                cluIDs = rez.SortaSort.ClusterID;
                
                clus = [];
                spks = [];
                for j = 1:length(cluIDs)
                    currSpks = rez.SortaSort.GroupedSpikes{j};
                    spks = [spks; currSpks];
                    clus = [clus; str2num(cluIDs{j})*ones(length(currSpks),1)];
                end

                [spks, inds] = sort(spks);
                clus = clus(inds);

                writeNPY(clus, cluPath)
                writeNPY(spks, timesPath)

                groupCell = [{'cluster_id', 'SASLabel'}; ...
                            cluIDs, string(rez.SortaSort.ClusterType)];
                writematrix(groupCell, groupPath, 'filetype','text', 'delimiter','\t')
                cd(currDir)
                disp('Saved npy')
            catch
                disp('Nothing to save')
            end


        end

        % Menu selected function: OpenrezMenu
        function OpenrezMenuSelected(app, event)
            [fName, fPath] = uigetfile('*.mat', 'Select the rez file to load');

            load(fullfile(fPath, fName))
            
            app.rez = rez;
            app.mmfFil = memmapfile(rez.ops.fbinary,'format',{rez.ops.dType ...
                    [rez.ops.NchanTOT rez.ops.sampsToRead] 'file'});
            app.SelClusters = 1;
            app.CurrPlotClusters = nan;
            app.CurrFPath = fPath;
            
            app.rez.SortaSort.Settings = struct('uVScale', 2.343, ... % convert binary values to microvolts
                                                        'uVSep', 100, ... % separation between voltage traces for trace plots
                                                        'XCorrWindow', 0.02, ... % duration of the XCorr window in seconds
                                                        'TraceWindow', 0.004, ... % separation between voltage traces for trace plots
                                                        'uVScaleBar', 25, ... % for scaling the waveforms plot
                                                        'msScaleBar', 1, ... % for scaling the waveforms plot
                                                        'MaxSelected', 10, ... % maximum number simultaneously selected clusters
                                                        'MaxSampleCC', inf, ... % maximum number of spikes to use when calculating the CC
                                                        'FullCCMatrix', false, ... % plot cc between each pair of units
                                                        'FollowUnit', false, ... % update waveform plot to center on selected unit
                                                        'FilterTrace', true, ... % high-pass filter traces for unit activity (300-5000Hz)
                                                        'AutoReplot', true); % automatically replot data with each selection
                                             
            app.figure1.Name = ['SortaSort' fPath];
            UpdateTable(app);
            disp('Loaded rez')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SortaSort';
            app.figure1.Tag = 'figure1';
            app.Untitled_1 = uimenu(app.figure1);
            app.Untitled_1.Text = 'File';
            app.Untitled_1.Tag = 'Untitled_1';
            app.menuOpen = uimenu(app.Untitled_1);
            app.menuOpen.MenuSelectedFcn = createCallbackFcn(app, @menuOpen_Callback, true);
            app.menuOpen.Text = 'Open npy';
            app.menuOpen.Tag = 'menuOpen';
            app.OpenrezMenu = uimenu(app.Untitled_1);
            app.OpenrezMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenrezMenuSelected, true);
            app.OpenrezMenu.Text = 'Open rez';
            app.menuSettings = uimenu(app.Untitled_1);
            app.menuSettings.MenuSelectedFcn = createCallbackFcn(app, @menuSettings_Callback, true);
            app.menuSettings.Text = 'Settings';
            app.menuSettings.Tag = 'menuSettings';
            app.SaverezMenu = uimenu(app.Untitled_1);
            app.SaverezMenu.MenuSelectedFcn = createCallbackFcn(app, @menuSave_Callback, true);
            app.SaverezMenu.Text = 'Save rez';
            app.menuSaveNPY = uimenu(app.Untitled_1);
            app.menuSaveNPY.MenuSelectedFcn = createCallbackFcn(app, @menuSaveNPYMenuSelected, true);
            app.menuSaveNPY.Text = 'Save npy';
            app.menuSaveNPY.Tag = 'menuSave';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Operations';
            app.uipanel3.Tag = 'uipanel3';
            app.pbMerge = uibutton(app.uipanel3, 'push');
            app.pbMerge.ButtonPushedFcn = createCallbackFcn(app, @pbMerge_Callback, true);
            app.pbMerge.Tag = 'pbMerge';
            app.pbMerge.Text = 'Merge Units';
            app.pbRenumberClusters = uibutton(app.uipanel3, 'push');
            app.pbRenumberClusters.ButtonPushedFcn = createCallbackFcn(app, @pbRenumberClusters_Callback, true);
            app.pbRenumberClusters.Tag = 'pbRenumberClusters';
            app.pbRenumberClusters.Text = 'Renumber Clusters';
            app.pbSort = uibutton(app.uipanel3, 'push');
            app.pbSort.ButtonPushedFcn = createCallbackFcn(app, @pbSort_Callback, true);
            app.pbSort.Tag = 'pbSort';
            app.pbSort.Text = 'Sort';
            app.pbSplit = uibutton(app.uipanel3, 'push');
            app.pbSplit.ButtonPushedFcn = createCallbackFcn(app, @pbSplit_Callback, true);
            app.pbSplit.Tag = 'pbSplit';
            app.pbSplit.Text = 'Split - Amp';
            app.pbSplitFet = uibutton(app.uipanel3, 'push');
            app.pbSplitFet.Tag = 'pbSplitFet';
            app.pbSplitFet.Enable = 'off';
            app.pbSplitFet.Text = 'Split - Fet';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Sorting features';
            app.uipanel1.Tag = 'uipanel1';
            app.tblSort = uitable(app.figure1);
            app.tblSort.CellEditCallback = createCallbackFcn(app, @tblSort_CellEditCallback, true);
            app.tblSort.CellSelectionCallback = createCallbackFcn(app, @tblSort_CellSelectionCallback, true);
            app.tblSort.Tag = 'tblSort';
            app.pmCol1 = uidropdown(app.figure1);
            app.pmCol1.Items = {'Cluster ID', 'Cluster Type', 'Spatial Profile', 'Peak Location', 'Complete Waveform', 'Complete Spectrum', 'Peak Waveform', 'Peak Spectrum', 'Peak Channel', 'Spread', 'Peak X', 'Peak Y'};
            app.pmCol1.ValueChangedFcn = createCallbackFcn(app, @pmCol1_Callback, true);
            app.pmCol1.Tag = 'pmCol1';
            app.pmCol1.Value = 'Cluster ID';
            app.pmCol2 = uidropdown(app.figure1);
            app.pmCol2.Items = {'Cluster ID', 'Cluster Type', 'Spatial Profile', 'Peak Location', 'Complete Waveform', 'Complete Spectrum', 'Peak Waveform', 'Peak Spectrum', 'Peak Channel', 'Spread', 'Peak X', 'Peak Y'};
            app.pmCol2.ValueChangedFcn = createCallbackFcn(app, @pmCol2_Callback, true);
            app.pmCol2.Tag = 'pmCol2';
            app.pmCol2.Value = 'Cluster Type';
            app.pmCol3 = uidropdown(app.figure1);
            app.pmCol3.Items = {'Cluster ID', 'Cluster Type', 'Spatial Profile', 'Peak Location', 'Complete Waveform', 'Complete Spectrum', 'Peak Waveform', 'Peak Spectrum', 'Peak Channel', 'Spread', 'Peak X', 'Peak Y'};
            app.pmCol3.ValueChangedFcn = createCallbackFcn(app, @pmCol3_Callback, true);
            app.pmCol3.Tag = 'pmCol3';
            app.pmCol3.Value = 'Spatial Profile';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Column 1:';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Column 2:';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Column 3:';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Plots';
            app.uipanel4.Tag = 'uipanel4';
            app.cbProfile = uicheckbox(app.uipanel4);
            app.cbProfile.ValueChangedFcn = createCallbackFcn(app, @cbProfile_Callback, true);
            app.cbProfile.Tag = 'cbProfile';
            app.cbProfile.Text = 'Profile';
            app.cbWaveform = uicheckbox(app.uipanel4);
            app.cbWaveform.ValueChangedFcn = createCallbackFcn(app, @cbWaveform_Callback, true);
            app.cbWaveform.Tag = 'cbWaveform';
            app.cbWaveform.Text = 'Waveform';
            app.cbXCorr = uicheckbox(app.uipanel4);
            app.cbXCorr.ValueChangedFcn = createCallbackFcn(app, @cbXCorr_Callback, true);
            app.cbXCorr.Tag = 'cbXCorr';
            app.cbXCorr.Text = 'XCorr';
            app.cbTrace = uicheckbox(app.uipanel4);
            app.cbTrace.ValueChangedFcn = createCallbackFcn(app, @cbTrace_Callback, true);
            app.cbTrace.Tag = 'cbTrace';
            app.cbTrace.Text = 'Trace';
            app.cbAmplitudes = uicheckbox(app.uipanel4);
            app.cbAmplitudes.ValueChangedFcn = createCallbackFcn(app, @cbAmplitudes_Callback, true);
            app.cbAmplitudes.Tag = 'cbAmplitudes';
            app.cbAmplitudes.Text = 'Amplitudes';
            app.pbReplotGraphs = uibutton(app.uipanel4, 'push');
            app.pbReplotGraphs.ButtonPushedFcn = createCallbackFcn(app, @pbReplotGraphs_Callback, true);
            app.pbReplotGraphs.Tag = 'pbReplotGraphs';
            app.pbReplotGraphs.Text = 'Replot Graphs';
            app.cbFeatures = uicheckbox(app.uipanel4);
            app.cbFeatures.ValueChangedFcn = createCallbackFcn(app, @cbFeatures_Callback, true);
            app.cbFeatures.Tag = 'cbFeatures';
            app.cbFeatures.Enable = 'off';
            app.cbFeatures.Text = 'Features';
            app.lblUnitCounts = uilabel(app.figure1);
            app.lblUnitCounts.Tag = 'lblUnitCounts';
            app.lblUnitCounts.Text = 'Unknown: 0, Noise: 0, MU: 0, SU: 0';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SortaSort2(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)SortaSort_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end